classdef ARbot_Sim_Interface_v0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        ConfigurationParametersPanel   matlab.ui.container.Panel
        TabGroup                       matlab.ui.container.TabGroup
        EnvironmentalTab               matlab.ui.container.Tab
        BeltSpeedButtonGroup           matlab.ui.container.ButtonGroup
        DefaultButton                  matlab.ui.control.RadioButton
        msButton                       matlab.ui.control.RadioButton
        msButton_2                     matlab.ui.control.RadioButton
        msButton_3                     matlab.ui.control.RadioButton
        msButton_4                     matlab.ui.control.RadioButton
        msButton_5                     matlab.ui.control.RadioButton
        msButton_6                     matlab.ui.control.RadioButton
        msButton_7                     matlab.ui.control.RadioButton
        TargetsTab                     matlab.ui.container.Tab
        TargetsLabel                   matlab.ui.control.Label
        SpawnRateSliderLabel           matlab.ui.control.Label
        SpawnRateSlider                matlab.ui.control.Slider
        PicksMinuteLabel               matlab.ui.control.Label
        DimensionWidthButtonGroup      matlab.ui.container.ButtonGroup
        RandomDefaultButton            matlab.ui.control.RadioButton
        SmallButton                    matlab.ui.control.RadioButton
        MediumButton                   matlab.ui.control.RadioButton
        LargeButton                    matlab.ui.control.RadioButton
        DimensionLengthButtonGroup     matlab.ui.container.ButtonGroup
        RandomDefaultButton_2          matlab.ui.control.RadioButton
        SmallButton_2                  matlab.ui.control.RadioButton
        MediumButton_2                 matlab.ui.control.RadioButton
        LargeButton_2                  matlab.ui.control.RadioButton
        SpawnLocationDifficultyButtonGroup  matlab.ui.container.ButtonGroup
        EasyButton                     matlab.ui.control.RadioButton
        MediumButton_3                 matlab.ui.control.RadioButton
        HardButton                     matlab.ui.control.RadioButton
        PerformanceTab                 matlab.ui.container.Tab
        MaximumValuesLabel             matlab.ui.control.Label
        VelocityLabel                  matlab.ui.control.Label
        Joint1EditFieldLabel           matlab.ui.control.Label
        Joint1EditField                matlab.ui.control.NumericEditField
        Joint2EditFieldLabel           matlab.ui.control.Label
        Joint2EditField                matlab.ui.control.NumericEditField
        Joint3EditFieldLabel           matlab.ui.control.Label
        Joint3EditField                matlab.ui.control.NumericEditField
        Joint4EditFieldLabel           matlab.ui.control.Label
        Joint4EditField                matlab.ui.control.NumericEditField
        Joint1EditField_2              matlab.ui.control.NumericEditField
        Joint2EditField_2              matlab.ui.control.NumericEditField
        Joint3EditField_2              matlab.ui.control.NumericEditField
        Joint4EditField_2              matlab.ui.control.NumericEditField
        Joint1EditField_3              matlab.ui.control.NumericEditField
        Joint2EditField_3              matlab.ui.control.NumericEditField
        Joint3EditField_3              matlab.ui.control.NumericEditField
        Joint4EditField_3              matlab.ui.control.NumericEditField
        AccelerationLabel              matlab.ui.control.Label
        TorqueLabel                    matlab.ui.control.Label
        CameraFrameRateEditFieldLabel  matlab.ui.control.Label
        CameraFrameRateEditField       matlab.ui.control.NumericEditField
        CameraLabel                    matlab.ui.control.Label
        PathsTab                       matlab.ui.container.Tab
        AddpathstolocalfiledirectoriesLabel  matlab.ui.control.Label
        SimulinkModelEditFieldLabel    matlab.ui.control.Label
        SimulinkModelEditField         matlab.ui.control.EditField
        PerformanceMetricsPanel        matlab.ui.container.Panel
        TabGroup2                      matlab.ui.container.TabGroup
        LiveMonitorTab                 matlab.ui.container.Tab
        UIAxes4                        matlab.ui.control.UIAxes
        UIAxes4_2                      matlab.ui.control.UIAxes
        UIAxes4_3                      matlab.ui.control.UIAxes
        UIAxes4_4                      matlab.ui.control.UIAxes
        Joint1Label                    matlab.ui.control.Label
        Joint2Label                    matlab.ui.control.Label
        Joint3Label                    matlab.ui.control.Label
        Joint4Label                    matlab.ui.control.Label
        ReportsTab                     matlab.ui.container.Tab
        UITable                        matlab.ui.control.Table
        StartButton                    matlab.ui.control.StateButton
        StopButton                     matlab.ui.control.StateButton
        ResetButton                    matlab.ui.control.Button
        GenerateReportButton           matlab.ui.control.Button
    end

    
    properties (Access = private)
        startup = 'None';
        file = 'None';
        box_offsets_x; % x and y offsets for the boxes
        box_offsets_y;
        box_dim_w;
        box_dim_l;
        
        tor1 = [];
        tor2 = [];
        tor3 = [];
        tor4 = [];
        accel1 = [];
        accel2 = [];
        accel3 = [];
        accel4 = [];
        vel1 = [];
        vel2 = [];
        vel3 = [];
        vel4 = [];
        pos1 = [];
        pos2 = [];
        pos3 = [];
        pos4 = [];
        time = [];
        
        data_store = [];
     
        belt_speed = 1.1295;
        CPU_time = 0.7;
    end
    
    methods (Access = public)
        function update_data(app, torq, accel, vel, pos, t, x)
            app.time(x,1) = [t];
            
            app.tor1(x,1) = [torq(1)];
            app.tor2(x,1) = [torq(2)];
            app.tor3(x,1) = [torq(3)];
            app.tor4(x,1) = [torq(4)];
            
            app.accel1(x,1) = [accel(1)];
            app.accel2(x,1) = [accel(2)];
            app.accel3(x,1) = [accel(3)];
            app.accel4(x,1) = [accel(4)];    
            
            app.vel1(x,1) = [vel(1)];
            app.vel2(x,1) = [vel(2)];
            app.vel3(x,1) = [vel(3)];
            app.vel4(x,1) = [vel(4)];
            
            app.pos1(x,1) = [pos(1)];
            app.pos2(x,1) = [pos(2)];
            app.pos3(x,1) = [pos(3)];
            app.pos4(x,1) = [pos(4)];   

            app.UITable.Data = [app.time, app.tor1, app.tor2, app.tor3, app.tor4,...
                                app.accel1, app.accel2, app.accel3, app.accel4,...
                                app.vel1, app.vel2, app.vel3, app.vel4,...
                                app.pos1, app.pos2, app.pos3, app.pos4];              
            if x==1
                app.data_store = {'Time', 'Torq1', 'Torq2', 'Torq3', 'Tor4',...
                                    'Accel1', 'Accel2', 'Accel3', 'Accel4',...
                                    'Vel1', 'Vel2', 'Vel3', 'Vel4',...
                                    'Pos1', 'Pos2', 'Pos3', 'Pos4'};
                                
                  T = fopen('C:/Users/Clayton Tan/Desktop/Performance_Report.csv','w');
                  fprintf(T,'%s, %s, %s, %s, %s, %s, %s, %s, %s, %s, %s, %s, %s, %s, %s, %s, %s\n',app.data_store{1,:});
                  fclose(T);
%                 writematrix(app.data_store, 'C:/Users/Clayton Tan/Desktop/Performance_Report.csv','WriteMode','append');             
            end
            app.data_store = app.UITable.Data;  
            writematrix(app.data_store, 'C:/Users/Clayton Tan/Desktop/Performance_Report.csv','WriteMode','append');
                            
            plot(app.UIAxes4, app.time, app.tor1, '-r');
            hold(app.UIAxes4,'on');
            plot(app.UIAxes4, app.time, app.tor2, '-b');
            hold(app.UIAxes4,'on');
            plot(app.UIAxes4, app.time, app.tor3, '-g');
            hold(app.UIAxes4,'on');         
            plot(app.UIAxes4, app.time, app.tor4, '-y');
            hold(app.UIAxes4,'on');   
            
            plot(app.UIAxes4_2, app.time, app.accel1, '-r');
            hold(app.UIAxes4_2,'on');
            plot(app.UIAxes4_2, app.time, app.accel2, '-b');
            hold(app.UIAxes4_2,'on');
            plot(app.UIAxes4_2, app.time, app.accel3, '-g');
            hold(app.UIAxes4_2,'on');         
            plot(app.UIAxes4_2, app.time, app.accel4, '-y');
            hold(app.UIAxes4_2,'on');   
            
            plot(app.UIAxes4_3, app.time, app.vel1, '-r');
            hold(app.UIAxes4_3,'on');
            plot(app.UIAxes4_3, app.time, app.vel2, '-b');
            hold(app.UIAxes4_3,'on');
            plot(app.UIAxes4_3, app.time, app.vel3, '-g');
            hold(app.UIAxes4_3,'on');         
            plot(app.UIAxes4_3, app.time, app.vel4, '-y');
            hold(app.UIAxes4_3,'on');   
            
            plot(app.UIAxes4_4, app.time, app.pos1, '-r');
            hold(app.UIAxes4_4,'on');
            plot(app.UIAxes4_4, app.time, app.pos2, '-b');
            hold(app.UIAxes4_4,'on');
            plot(app.UIAxes4_4, app.time, app.pos3, '-g');
            hold(app.UIAxes4_4,'on');         
            plot(app.UIAxes4_4, app.time, app.pos4, '-y');
            hold(app.UIAxes4_4,'on');                    
        end
    end


    methods (Access = private)
        
        function results = update_x_offsets(app)
            selected = app.SpawnLocationDifficultyButtonGroup.SelectedObject;
            width=1.1225;
            easy_range = [width/3 width*2/3]; % middle third
            med_range = [width/4 width*3/4]; % 25% to 75% of belt
            hard_range = [0 med_range width];
            switch(selected.Text)
                case 'Easy'
                    app.box_offsets_x(:,1) = easy_range(1) + diff(easy_range).*rand(5,1);
                case 'Medium'
                    app.box_offsets_x(:,1) = med_range(1) + diff(med_range).*rand(5,1);
                case 'Hard'
                    random_vector = round(rand(5,1))
                    for i = 1:length(random_vector)
                        if random_vector(i) 
                            app.box_offsets_x(i,1) = hard_range(3) + diff(hard_range(3:4))*rand(1,1);
                        else
                            app.box_offsets_x(i,1) = hard_range(1) + diff(hard_range(1:2))*rand(1,1);
                        end
                    end  
            end
            results = app.box_offsets_x;
        end
        
        function results = update_y_offsets(app)
            spawn_rate = app.SpawnRateSlider.Value;
            spawn_rate = ceil(spawn_rate);
            distance = 60/spawn_rate;
            start_offset = 1; %1 m
            speed = app.belt_speed + 0.04
            sep_vector = 0:distance*speed:distance*4*speed;
            for i = 1:length(sep_vector)
                app.box_offsets_y(i,1) = start_offset + sep_vector(i);
            end
            results = app.box_offsets_y;
        end
        
        function results = update_width(app)
            selectedButton = app.DimensionWidthButtonGroup.SelectedObject;
            width_range = [0.05 0.2];small_range = [0.05 .1];
            med_range = [.1 .15];large_range = [.15 .2];
            switch selectedButton.Text
                case 'Random (Default)'
                    app.box_dim_w(:,1) = width_range(1) + diff(width_range).*rand(5,1);
                case 'Small'
                    app.box_dim_w(:,1) = small_range(1) + diff(small_range).*rand(5,1);
                case 'Medium'
                    app.box_dim_w(:,1) = med_range(1) + diff(med_range).*rand(5,1);
                case 'Large'
                    app.box_dim_w(:,1) = large_range(1) + diff(large_range).*rand(5,1);
            end
            results = app.box_dim_w;
        end
        
        function results = update_length(app)
            selectedButton = app.DimensionLengthButtonGroup.SelectedObject;
            width_range = [0.05 0.2];small_range = [0.05 .1];
            med_range = [.1 .15];large_range = [.15 .2];
            switch selectedButton.Text
                case 'Random (Default)'
                    app.box_dim_l(:,1) = width_range(1) + diff(width_range).*rand(5,1)
                case 'Small'
                    app.box_dim_l(:,1) = small_range(1) + diff(small_range).*rand(5,1)
                case 'Medium'
                    app.box_dim_l(:,1) = med_range(1) + diff(med_range).*rand(5,1)
                case 'Large'
                    app.box_dim_l(:,1) = large_range(1) + diff(large_range).*rand(5,1)
            end
            results = app.box_dim_l;
        end
        
        function [speed,time] = update_belt_speed(app)
            selectedButton = app.BeltSpeedButtonGroup.SelectedObject;
            switch selectedButton.Text
                case 'Default'
                    app.belt_speed = 1.1295;
                    app.CPU_time = 0.7;
                case '0.5 m/s'
                    app.belt_speed = 0.5;
                    app.CPU_time = 0.7;
                case '0.75 m/s'
                    app.belt_speed = 0.75;
                    app.CPU_time = 0.7;
                case '1 m/s'
                    app.belt_speed = 1;
                    app.CPU_time = 0.7;
                case '1.25 m/s'
                    app.belt_speed = 1.25;
                    app.CPU_time = 0.7;
                case '1.5 m/s'
                    app.belt_speed = 1.5;
                    app.CPU_time = 0.7;
                case '1.75 m/s'
                    app.belt_speed = 1.75;
                    app.CPU_time = 0.15;
                case '2 m/s'
                    app.belt_speed = 2;
                    app.CPU_time = 0.15;
            end
            speed = app.belt_speed;
            time = app.CPU_time;
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: SimulinkModelEditField
        function SimulinkModelEditFieldValueChanged(app, event)
            value = app.SimulinkModelEditField.Value;
            
%             parent = '../AppDesigner';
            environment = '../Environment';
            libraries = '../Libraries';
            appdesign = '../AppDesigner';

%             addpath(genpath(parent));
            addpath(genpath(environment));
            addpath(genpath(libraries));
            addpath(genpath(appdesign));
%             app.startup = sprintf('C:/Users/Clayton Tan/Desktop/ARbot_Simulink/v2beta/Spawn/%s',value);
            app.startup = sprintf('C:/Users/Clayton Tan/Desktop/ARbot_Simulink/AppDesigner/%s',value);
            app.file = value;
        end

        % Callback function
        function LoadButtonPushed(app, event)
%              run(app.startup);
%              pause(20);
%              sim(app.startup);
%              app.EditField.Value = sprintf('Successfully Startup of Simulink Model: %s', app.file);
%              app.EditField_2.Value = 'Report Generated';
        end

        % Value changed function: StopButton
        function StopButtonValueChanged(app, event)
            value = app.StopButton.Value;
            set_param(gcs, 'SimulationCommand', 'stop');
        end

        % Value changed function: StartButton
        function StartButtonPushed(app, event)
            value = app.StartButton.Value;
            
            [app.belt_speed,app.CPU_time] = update_belt_speed(app)
            belt_spd = app.belt_speed;
            inherentCPUCost = app.CPU_time;
            
            app.box_offsets_x = update_x_offsets(app);
            app.box_offsets_y = update_y_offsets(app);
            box_positions = [(app.box_offsets_x) (app.box_offsets_y)];
            
            app.box_dim_w = update_width(app);
            app.box_dim_l = update_length(app);
            box_dim = [(app.box_dim_w) (app.box_dim_l)];
            app.startup = 'C:/Users/Clayton Tan/Desktop/ARbot_Simulink/AppDesigner/app_designer_model_martin.slx';
            if value
                run(app.startup);
                save("user_inputs.mat", 'box_positions', 'box_dim','belt_spd','inherentCPUCost');
                sim(app.startup);
            end
        end

        % Button pushed function: GenerateReportButton
        function GenerateReportButtonPushed(app, event)
%             t= readtable('test.txt');
%             app.UITable.Data = t;
%             t.Properties.VariableNames{1}= 'Max_Accel';
%             t.Properties.VariableNames{2}= 'Max_Vel';
%             t.Properties.VariableNames{3}= 'J1_Tq';
%             t.Properties.VariableNames{4}= 'J2_Tq';
%             t.Properties.VariableNames{5}= 'J3_Tq';
%             t.Properties.VariableNames{6}= 'J4_Tq';
%             t.Properties.VariableNames{7}= 'Current_Pos';
%             t.Properties.VariableNames{8}= 'Sorting_Accuracy';
%             t.Properties.VariableNames{9}= 'Sorting_Speed';
%             app.UITable.ColumnName = t.Properties.VariableNames;
        end

        % Size changed function: PathsTab
        function PathsTabSizeChanged(app, event)
            position = app.PathsTab.Position;
            
        end

        % Value changing function: SimulinkModelEditField
        function SimulinkModelEditFieldValueChanging(app, event)
            changingValue = event.Value;
            
        end

        % Selection changed function: DimensionWidthButtonGroup
        function DimWidth(app, event)
            selectedButton = app.DimensionWidthButtonGroup.SelectedObject;
            width_range = [0.05 0.2];small_range = [0.05 .1];
            med_range = [.1 .15];large_range = [.15 .2];
            switch selectedButton.Text
                case 'Random (Default)'
                    app.box_dim_w(:,1) = width_range(1) + diff(width_range).*rand(5,1)
                case 'Small'
                    app.box_dim_w(:,1) = small_range(1) + diff(small_range).*rand(5,1)
                case 'Medium'
                    app.box_dim_w(:,1) = med_range(1) + diff(med_range).*rand(5,1)
                case 'Large'
                    app.box_dim_w(:,1) = large_range(1) + diff(large_range).*rand(5,1)
            end
            
            
        end

        % Selection changed function: DimensionLengthButtonGroup
        function DimLength(app, event)
            selectedButton = app.DimensionLengthButtonGroup.SelectedObject;
            width_range = [0.05 0.2];small_range = [0.05 .1];
            med_range = [.1 .15];large_range = [.15 .2];
            switch selectedButton.Text
                case 'Random (Default)'
                    app.box_dim_l(:,1) = width_range(1) + diff(width_range).*rand(5,1)
                case 'Small'
                    app.box_dim_l(:,1) = small_range(1) + diff(small_range).*rand(5,1)
                case 'Medium'
                    app.box_dim_l(:,1) = med_range(1) + diff(med_range).*rand(5,1)
                case 'Large'
                    app.box_dim_l(:,1) = large_range(1) + diff(large_range).*rand(5,1)
            end
        end

        % Value changed function: SpawnRateSlider
        function SpawnRate(app, event)
            app.box_offsets_y = update_y_offsets(app)
            
        end

        % Selection changed function: 
        % SpawnLocationDifficultyButtonGroup
        function SpawnLocation(app, event)
            app.box_offsets_x = update_x_offsets(app)
            
        end

        % Selection changed function: BeltSpeedButtonGroup
        function BeltSpeed(app, event)
            [app.belt_speed,app.CPU_time] = update_belt_speed(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ConfigurationParametersPanel = uipanel(app.UIFigure);
            app.ConfigurationParametersPanel.Title = 'Configuration Parameters';
            app.TabGroup = uitabgroup(app.ConfigurationParametersPanel);
            app.EnvironmentalTab = uitab(app.TabGroup);
            app.EnvironmentalTab.Title = 'Environmental';
            app.BeltSpeedButtonGroup = uibuttongroup(app.EnvironmentalTab);
            app.BeltSpeedButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BeltSpeed, true);
            app.BeltSpeedButtonGroup.Title = 'Belt Speed';
            app.DefaultButton = uiradiobutton(app.BeltSpeedButtonGroup);
            app.DefaultButton.Text = 'Default';
            app.DefaultButton.Value = true;
            app.msButton = uiradiobutton(app.BeltSpeedButtonGroup);
            app.msButton.Text = '0.5 m/s';
            app.msButton_2 = uiradiobutton(app.BeltSpeedButtonGroup);
            app.msButton_2.Text = '0.75 m/s';
            app.msButton_3 = uiradiobutton(app.BeltSpeedButtonGroup);
            app.msButton_3.Text = '1 m/s';
            app.msButton_4 = uiradiobutton(app.BeltSpeedButtonGroup);
            app.msButton_4.Text = '1.25 m/s';
            app.msButton_5 = uiradiobutton(app.BeltSpeedButtonGroup);
            app.msButton_5.Text = '1.5 m/s';
            app.msButton_6 = uiradiobutton(app.BeltSpeedButtonGroup);
            app.msButton_6.Text = '1.75 m/s';
            app.msButton_7 = uiradiobutton(app.BeltSpeedButtonGroup);
            app.msButton_7.Text = '2 m/s';
            app.TargetsTab = uitab(app.TabGroup);
            app.TargetsTab.Title = 'Targets';
            app.TargetsLabel = uilabel(app.TargetsTab);
            app.TargetsLabel.Text = 'Targets ';
            app.SpawnRateSliderLabel = uilabel(app.TargetsTab);
            app.SpawnRateSliderLabel.Text = 'Spawn Rate';
            app.SpawnRateSlider = uislider(app.TargetsTab);
            app.SpawnRateSlider.Limits = [10 30];
            app.SpawnRateSlider.ValueChangedFcn = createCallbackFcn(app, @SpawnRate, true);
            app.SpawnRateSlider.Value = 30;
            app.PicksMinuteLabel = uilabel(app.TargetsTab);
            app.PicksMinuteLabel.Text = 'Picks/Minute';
            app.DimensionWidthButtonGroup = uibuttongroup(app.TargetsTab);
            app.DimensionWidthButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DimWidth, true);
            app.DimensionWidthButtonGroup.Title = 'Dimension Width';
            app.RandomDefaultButton = uiradiobutton(app.DimensionWidthButtonGroup);
            app.RandomDefaultButton.Text = 'Random (Default)';
            app.RandomDefaultButton.Value = true;
            app.SmallButton = uiradiobutton(app.DimensionWidthButtonGroup);
            app.SmallButton.Text = 'Small';
            app.MediumButton = uiradiobutton(app.DimensionWidthButtonGroup);
            app.MediumButton.Text = 'Medium';
            app.LargeButton = uiradiobutton(app.DimensionWidthButtonGroup);
            app.LargeButton.Text = 'Large';
            app.DimensionLengthButtonGroup = uibuttongroup(app.TargetsTab);
            app.DimensionLengthButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DimLength, true);
            app.DimensionLengthButtonGroup.Title = 'Dimension Length';
            app.RandomDefaultButton_2 = uiradiobutton(app.DimensionLengthButtonGroup);
            app.RandomDefaultButton_2.Text = 'Random (Default)';
            app.RandomDefaultButton_2.Value = true;
            app.SmallButton_2 = uiradiobutton(app.DimensionLengthButtonGroup);
            app.SmallButton_2.Text = 'Small';
            app.MediumButton_2 = uiradiobutton(app.DimensionLengthButtonGroup);
            app.MediumButton_2.Text = 'Medium';
            app.LargeButton_2 = uiradiobutton(app.DimensionLengthButtonGroup);
            app.LargeButton_2.Text = 'Large';
            app.SpawnLocationDifficultyButtonGroup = uibuttongroup(app.TargetsTab);
            app.SpawnLocationDifficultyButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SpawnLocation, true);
            app.SpawnLocationDifficultyButtonGroup.Title = 'Spawn Location Difficulty';
            app.EasyButton = uiradiobutton(app.SpawnLocationDifficultyButtonGroup);
            app.EasyButton.Text = 'Easy';
            app.EasyButton.Value = true;
            app.MediumButton_3 = uiradiobutton(app.SpawnLocationDifficultyButtonGroup);
            app.MediumButton_3.Text = 'Medium';
            app.HardButton = uiradiobutton(app.SpawnLocationDifficultyButtonGroup);
            app.HardButton.Text = 'Hard';
            app.PerformanceTab = uitab(app.TabGroup);
            app.PerformanceTab.Title = 'Performance';
            app.MaximumValuesLabel = uilabel(app.PerformanceTab);
            app.MaximumValuesLabel.Text = 'Maximum Values';
            app.VelocityLabel = uilabel(app.PerformanceTab);
            app.VelocityLabel.Text = 'Velocity';
            app.Joint1EditFieldLabel = uilabel(app.PerformanceTab);
            app.Joint1EditFieldLabel.Text = 'Joint 1';
            app.Joint1EditField = uieditfield(app.PerformanceTab, 'numeric');
            app.Joint2EditFieldLabel = uilabel(app.PerformanceTab);
            app.Joint2EditFieldLabel.Text = 'Joint 2';
            app.Joint2EditField = uieditfield(app.PerformanceTab, 'numeric');
            app.Joint3EditFieldLabel = uilabel(app.PerformanceTab);
            app.Joint3EditFieldLabel.Text = 'Joint 3';
            app.Joint3EditField = uieditfield(app.PerformanceTab, 'numeric');
            app.Joint4EditFieldLabel = uilabel(app.PerformanceTab);
            app.Joint4EditFieldLabel.Text = 'Joint 4';
            app.Joint4EditField = uieditfield(app.PerformanceTab, 'numeric');
            app.Joint1EditField_2 = uieditfield(app.PerformanceTab, 'numeric');
            app.Joint2EditField_2 = uieditfield(app.PerformanceTab, 'numeric');
            app.Joint3EditField_2 = uieditfield(app.PerformanceTab, 'numeric');
            app.Joint4EditField_2 = uieditfield(app.PerformanceTab, 'numeric');
            app.Joint1EditField_3 = uieditfield(app.PerformanceTab, 'numeric');
            app.Joint2EditField_3 = uieditfield(app.PerformanceTab, 'numeric');
            app.Joint3EditField_3 = uieditfield(app.PerformanceTab, 'numeric');
            app.Joint4EditField_3 = uieditfield(app.PerformanceTab, 'numeric');
            app.AccelerationLabel = uilabel(app.PerformanceTab);
            app.AccelerationLabel.Text = 'Acceleration';
            app.TorqueLabel = uilabel(app.PerformanceTab);
            app.TorqueLabel.Text = 'Torque';
            app.CameraFrameRateEditFieldLabel = uilabel(app.PerformanceTab);
            app.CameraFrameRateEditFieldLabel.Text = 'Camera Frame Rate';
            app.CameraFrameRateEditField = uieditfield(app.PerformanceTab, 'numeric');
            app.CameraLabel = uilabel(app.PerformanceTab);
            app.CameraLabel.Text = 'Camera ';
            app.PathsTab = uitab(app.TabGroup);
            app.PathsTab.SizeChangedFcn = createCallbackFcn(app, @PathsTabSizeChanged, true);
            app.PathsTab.Title = 'Paths';
            app.AddpathstolocalfiledirectoriesLabel = uilabel(app.PathsTab);
            app.AddpathstolocalfiledirectoriesLabel.Text = 'Add paths to local file directories';
            app.SimulinkModelEditFieldLabel = uilabel(app.PathsTab);
            app.SimulinkModelEditFieldLabel.Text = 'Simulink Model';
            app.SimulinkModelEditField = uieditfield(app.PathsTab, 'text');
            app.SimulinkModelEditField.ValueChangedFcn = createCallbackFcn(app, @SimulinkModelEditFieldValueChanged, true);
            app.SimulinkModelEditField.ValueChangingFcn = createCallbackFcn(app, @SimulinkModelEditFieldValueChanging, true);
            app.PerformanceMetricsPanel = uipanel(app.UIFigure);
            app.PerformanceMetricsPanel.Title = 'Performance Metrics';
            app.TabGroup2 = uitabgroup(app.PerformanceMetricsPanel);
            app.LiveMonitorTab = uitab(app.TabGroup2);
            app.LiveMonitorTab.Title = 'Live Monitor';
            app.UIAxes4 = uiaxes(app.LiveMonitorTab);
            title(app.UIAxes4, 'Torque vs. Time')
            xlabel(app.UIAxes4, 'Time (sec)')
            ylabel(app.UIAxes4, 'Torque (N-m)')
            app.UIAxes4_2 = uiaxes(app.LiveMonitorTab);
            title(app.UIAxes4_2, 'Accel vs. Time')
            xlabel(app.UIAxes4_2, 'Time (sec)')
            ylabel(app.UIAxes4_2, 'Accel (m/s^2)')
            app.UIAxes4_3 = uiaxes(app.LiveMonitorTab);
            title(app.UIAxes4_3, 'Vel vs. Time')
            xlabel(app.UIAxes4_3, 'Time (sec)')
            ylabel(app.UIAxes4_3, 'Vel (m/s)')
            app.UIAxes4_4 = uiaxes(app.LiveMonitorTab);
            title(app.UIAxes4_4, 'Pos vs. Time')
            xlabel(app.UIAxes4_4, 'Time (sec)')
            ylabel(app.UIAxes4_4, 'Pos (m)')
            app.Joint1Label = uilabel(app.LiveMonitorTab);
            app.Joint1Label.Text = 'Joint 1';
            app.Joint2Label = uilabel(app.LiveMonitorTab);
            app.Joint2Label.Text = 'Joint 2';
            app.Joint3Label = uilabel(app.LiveMonitorTab);
            app.Joint3Label.Text = 'Joint 3';
            app.Joint4Label = uilabel(app.LiveMonitorTab);
            app.Joint4Label.Text = 'Joint 4';
            app.ReportsTab = uitab(app.TabGroup2);
            app.ReportsTab.Title = 'Reports';
            app.UITable = uitable(app.ReportsTab);
            app.UITable.ColumnName = {'Time'; 'Torq1'; 'Torq2'; 'Torq3'; 'Torq4'; 'Accel1'; 'Accel2'; 'Accel3'; 'Accel4'; 'Vel1'; 'Vel2'; 'Vel3'; 'Vel4'; 'Pos1'; 'Pos2'; 'Pos3'; 'Pos4'; 'EE'};
            app.UITable.RowName = {};
            app.StartButton = uibutton(app.UIFigure, 'state');
            app.StartButton.ValueChangedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.StopButton = uibutton(app.UIFigure, 'state');
            app.StopButton.ValueChangedFcn = createCallbackFcn(app, @StopButtonValueChanged, true);
            app.StopButton.Text = 'Stop';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.Text = 'Reset';
            app.GenerateReportButton = uibutton(app.UIFigure, 'push');
            app.GenerateReportButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateReportButtonPushed, true);
            app.GenerateReportButton.Text = 'Generate Report';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ARbot_Sim_Interface_v0

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end